classdef baiTapLon_PPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Tim_Nghiem_Tab             matlab.ui.container.Tab
        NhpphngtrnhEditFieldLabel  matlab.ui.control.Label
        inputEquation              matlab.ui.control.EditField
        NhpkhongphnlynghimaLabel   matlab.ui.control.Label
        inputSeparationDistance_a  matlab.ui.control.EditField
        NhpsaischophpLabel         matlab.ui.control.Label
        inputAllowableError        matlab.ui.control.EditField
        ChnphngphptmLabel          matlab.ui.control.Label
        choseOption                matlab.ui.control.DropDown
        NhpkhongphnlynghimbEditFieldLabel  matlab.ui.control.Label
        inputSeparationDistance_b  matlab.ui.control.EditField
        KtqunghimTextAreaLabel     matlab.ui.control.Label
        result                     matlab.ui.control.TextArea
        SlnlpTextAreaLabel         matlab.ui.control.Label
        numberLoop                 matlab.ui.control.TextArea
        UIAxes                     matlab.ui.control.UIAxes
        Noi_Suy_Tab                matlab.ui.container.Tab
        Hoi_Quy_Tab                matlab.ui.container.Tab
        Dao_Ham_Tab                matlab.ui.container.Tab
        Tich_Phan_Tab              matlab.ui.container.Tab
        Introduce_Tab              matlab.ui.container.Tab
        EditFieldLabel             matlab.ui.control.Label
        Test_Field                 matlab.ui.control.EditField
    end

    
    %this line trust comment in to test
    
    methods (Access = private)
        
        function [c, n] = bisectionMethod(app, fx, a,b, saiso ) %tạo function sử dụng phương pháp tìm nghiệm
            fa = feval(fx, a);
            fb = feval(fx, b);
            if(fa * fb) > 0
                fprintf("Không có nghiệm trong khoảng phân ly trên");
            end
            for n = 1:1:10000
                c = (a+b)/2
                fc = feval(fx, c);
                e = (a-b);
                caseMUL = fa * fc;
                if caseMUL < 0
                    b = c;
                else
                    a = c;
                end
                if caseMUL == 0
                    e = 0;
                end
                if  e < saiso
                    break;  
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: choseOption
        function choseOptionValueChanged(app, event)
           fx = app.inputEquation.Value;
           saiso = app.inputAllowableError.Value;
           a = app.inputSeparationDistance_a.Value;
           b = app.inputSeparationDistance_b.Value;
           
           
          chosess = app.choseOption.Value;
          fopen(['Giá trị được chọn', chosess]);
         % if app.choseOption.Items == [1]
          %    if 
           %   [c,n] = bisectionMethod(app, fx, a, b, saiso);
                
            %  fprintf(app.result, c);
          %end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tim_Nghiem_Tab = uitab(app.TabGroup);
            app.Tim_Nghiem_Tab.Title = 'Nghiệm';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.Tim_Nghiem_Tab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.inputEquation = uieditfield(app.Tim_Nghiem_Tab, 'text');
            app.NhpkhongphnlynghimaLabel = uilabel(app.Tim_Nghiem_Tab);
            app.NhpkhongphnlynghimaLabel.Text = 'Nhập khoảng phân ly nghiệm(a)';
            app.inputSeparationDistance_a = uieditfield(app.Tim_Nghiem_Tab, 'text');
            app.NhpsaischophpLabel = uilabel(app.Tim_Nghiem_Tab);
            app.NhpsaischophpLabel.Text = 'Nhập sai số cho phép';
            app.inputAllowableError = uieditfield(app.Tim_Nghiem_Tab, 'text');
            app.ChnphngphptmLabel = uilabel(app.Tim_Nghiem_Tab);
            app.ChnphngphptmLabel.Text = 'Chọn phương pháp tìm';
            app.choseOption = uidropdown(app.Tim_Nghiem_Tab);
            app.choseOption.Items = {'Chia đôi', 'Lặp', 'Newton(Tiếp tuyến)'};
            app.choseOption.ValueChangedFcn = createCallbackFcn(app, @choseOptionValueChanged, true);
            app.choseOption.Value = 'Chia đôi';
            app.NhpkhongphnlynghimbEditFieldLabel = uilabel(app.Tim_Nghiem_Tab);
            app.NhpkhongphnlynghimbEditFieldLabel.Text = 'Nhập khoảng phân ly nghiệm(b)';
            app.inputSeparationDistance_b = uieditfield(app.Tim_Nghiem_Tab, 'text');
            app.KtqunghimTextAreaLabel = uilabel(app.Tim_Nghiem_Tab);
            app.KtqunghimTextAreaLabel.Text = 'Kết quả nghiệm';
            app.result = uitextarea(app.Tim_Nghiem_Tab);
            app.SlnlpTextAreaLabel = uilabel(app.Tim_Nghiem_Tab);
            app.SlnlpTextAreaLabel.Text = 'Số lần lặp';
            app.numberLoop = uitextarea(app.Tim_Nghiem_Tab);
            app.UIAxes = uiaxes(app.Tim_Nghiem_Tab);
            title(app.UIAxes, 'Đồ thị hàm số  của phương trình')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Noi_Suy_Tab = uitab(app.TabGroup);
            app.Noi_Suy_Tab.Title = 'Nội Suy';
            app.Hoi_Quy_Tab = uitab(app.TabGroup);
            app.Hoi_Quy_Tab.Title = 'Hồi Quy';
            app.Dao_Ham_Tab = uitab(app.TabGroup);
            app.Dao_Ham_Tab.Title = 'Đạo Hàm';
            app.Tich_Phan_Tab = uitab(app.TabGroup);
            app.Tich_Phan_Tab.Title = 'Tích Phân';
            app.Introduce_Tab = uitab(app.TabGroup);
            app.Introduce_Tab.Title = 'Giới Thiệu Nhóm';
            app.EditFieldLabel = uilabel(app.Introduce_Tab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.Test_Field = uieditfield(app.Introduce_Tab, 'text');
            app.Test_Field.Value = 'Tab này cho Tâm làm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = baiTapLon_PPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end